classdef HexaTrack_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        RotationalTab            matlab.ui.container.Tab
        STATUSLabel              matlab.ui.control.Label
        Lamp                     matlab.ui.control.Lamp
        Label_2                  matlab.ui.control.Label
        PerturbationTypeLabel    matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        UserDefinedButton        matlab.ui.control.Button
        RandomButton             matlab.ui.control.Button
        EditField_3              matlab.ui.control.NumericEditField
        Label_6                  matlab.ui.control.Label
        EditField_2              matlab.ui.control.NumericEditField
        Label_5                  matlab.ui.control.Label
        SUBMITButton             matlab.ui.control.Button
        EditField                matlab.ui.control.NumericEditField
        XULabel                  matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        PlotErrorX               matlab.ui.control.UIAxes
        PlotErrorY               matlab.ui.control.UIAxes
        PlotErrorZ               matlab.ui.control.UIAxes
        PlotZ                    matlab.ui.control.UIAxes
        PlotY                    matlab.ui.control.UIAxes
        PlotX                    matlab.ui.control.UIAxes
        TranslationalTab         matlab.ui.container.Tab
        mmLabel_3                matlab.ui.control.Label
        mmLabel_2                matlab.ui.control.Label
        mmLabel                  matlab.ui.control.Label
        Label_8                  matlab.ui.control.Label
        PerturbationTypeLabel_3  matlab.ui.control.Label
        Label_7                  matlab.ui.control.Label
        SUBMITButton_3           matlab.ui.control.Button
        STATUSLabel_3            matlab.ui.control.Label
        Lamp_3                   matlab.ui.control.Lamp
        UserDefinedButton_3      matlab.ui.control.Button
        RandomButton_3           matlab.ui.control.Button
        XEditField               matlab.ui.control.NumericEditField
        XEditFieldLabel          matlab.ui.control.Label
        EditField_5              matlab.ui.control.NumericEditField
        ZLabel                   matlab.ui.control.Label
        EditField_4              matlab.ui.control.NumericEditField
        YLabel                   matlab.ui.control.Label
        Image2                   matlab.ui.control.Image
        STATUSLabel_2            matlab.ui.control.Label
        Lamp_2                   matlab.ui.control.Lamp
        Label_4                  matlab.ui.control.Label
        PerturbationTypeLabel_2  matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        UserDefinedButton_2      matlab.ui.control.Button
        RandomButton_2           matlab.ui.control.Button
        WEditField_2             matlab.ui.control.NumericEditField
        WEditField_2Label        matlab.ui.control.Label
        VEditField_2             matlab.ui.control.NumericEditField
        VEditField_2Label        matlab.ui.control.Label
        SUBMITButton_2           matlab.ui.control.Button
        UEditField_2             matlab.ui.control.NumericEditField
        UEditField_2Label        matlab.ui.control.Label
        PlotErrorX_2             matlab.ui.control.UIAxes
        PlotErrorY_2             matlab.ui.control.UIAxes
        PlotErrorZ_2             matlab.ui.control.UIAxes
        PlotZ_2                  matlab.ui.control.UIAxes
        PlotY_2                  matlab.ui.control.UIAxes
        PlotX_2                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            motionType = evalin('base','chosenDynamics');
        end

        % Button pushed function: RandomButton, RandomButton_3
        function RandomButtonPushed(app, event)
            assignin('base','PerturbationType',1);
        end

        % Button pushed function: UserDefinedButton, UserDefinedButton_3
        function UserDefinedButtonPushed(app, event)
            assignin('base','PerturbationType',2);
        end

        % Button pushed function: SUBMITButton, SUBMITButton_3
        function SUBMITButtonPushed(app, event)
            if strcmp(motionType,'ROT') == true

                assignin('base','UserInputX',app.EditField.Value);
                assignin('base','UserInputY',app.EditField_2.Value);
                assignin('base','UserInputZ',app.EditField_3.Value);
                drawnow;

            elseif strcmp(motionType,'TRA') == true

                assignin('base','UserInputX',app.EditField.Value);
                assignin('base','UserInputY',app.EditField_2.Value);
                assignin('base','UserInputZ',app.EditField_3.Value);
                drawnow;

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RotationalTab = uitab(app.TabGroup);
            app.RotationalTab.Title = 'Rotational';
            app.PlotX = uiaxes(app.RotationalTab);
            title(app.PlotX, 'Title')
            xlabel(app.PlotX, 'X')
            ylabel(app.PlotX, 'Y')
            zlabel(app.PlotX, 'Z')
            app.PlotY = uiaxes(app.RotationalTab);
            title(app.PlotY, 'Title')
            xlabel(app.PlotY, 'X')
            ylabel(app.PlotY, 'Y')
            zlabel(app.PlotY, 'Z')
            app.PlotZ = uiaxes(app.RotationalTab);
            title(app.PlotZ, 'Title')
            xlabel(app.PlotZ, 'X')
            ylabel(app.PlotZ, 'Y')
            zlabel(app.PlotZ, 'Z')
            app.PlotErrorZ = uiaxes(app.RotationalTab);
            title(app.PlotErrorZ, 'Title')
            xlabel(app.PlotErrorZ, 'X')
            ylabel(app.PlotErrorZ, 'Y')
            zlabel(app.PlotErrorZ, 'Z')
            app.PlotErrorY = uiaxes(app.RotationalTab);
            title(app.PlotErrorY, 'Title')
            xlabel(app.PlotErrorY, 'X')
            ylabel(app.PlotErrorY, 'Y')
            zlabel(app.PlotErrorY, 'Z')
            app.PlotErrorX = uiaxes(app.RotationalTab);
            title(app.PlotErrorX, 'Title')
            xlabel(app.PlotErrorX, 'X')
            ylabel(app.PlotErrorX, 'Y')
            zlabel(app.PlotErrorX, 'Z')
            app.Image = uiimage(app.RotationalTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Rotational_GUI.png');
            app.XULabel = uilabel(app.RotationalTab);
            app.XULabel.Text = 'ψ˚ = ';
            app.EditField = uieditfield(app.RotationalTab, 'numeric');
            app.SUBMITButton = uibutton(app.RotationalTab, 'push');
            app.SUBMITButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITButtonPushed, true);
            app.SUBMITButton.Text = 'SUBMIT';
            app.Label_5 = uilabel(app.RotationalTab);
            app.Label_5.Text = 'θ˚ = ';
            app.EditField_2 = uieditfield(app.RotationalTab, 'numeric');
            app.Label_6 = uilabel(app.RotationalTab);
            app.Label_6.Text = 'ɸ˚ = ';
            app.EditField_3 = uieditfield(app.RotationalTab, 'numeric');
            app.RandomButton = uibutton(app.RotationalTab, 'push');
            app.RandomButton.ButtonPushedFcn = createCallbackFcn(app, @RandomButtonPushed, true);
            app.RandomButton.Text = 'Random';
            app.UserDefinedButton = uibutton(app.RotationalTab, 'push');
            app.UserDefinedButton.ButtonPushedFcn = createCallbackFcn(app, @UserDefinedButtonPushed, true);
            app.UserDefinedButton.Text = 'User-Defined';
            app.Label = uilabel(app.RotationalTab);
            app.Label.Text = '0.0000';
            app.PerturbationTypeLabel = uilabel(app.RotationalTab);
            app.PerturbationTypeLabel.Text = 'Perturbation Type: ';
            app.Label_2 = uilabel(app.RotationalTab);
            app.Label_2.Text = ' ';
            app.Lamp = uilamp(app.RotationalTab);
            app.STATUSLabel = uilabel(app.RotationalTab);
            app.STATUSLabel.Text = '[ STATUS ]';
            app.TranslationalTab = uitab(app.TabGroup);
            app.TranslationalTab.Title = 'Translational';
            app.PlotX_2 = uiaxes(app.TranslationalTab);
            title(app.PlotX_2, 'Title')
            xlabel(app.PlotX_2, 'X')
            ylabel(app.PlotX_2, 'Y')
            zlabel(app.PlotX_2, 'Z')
            app.PlotY_2 = uiaxes(app.TranslationalTab);
            title(app.PlotY_2, 'Title')
            xlabel(app.PlotY_2, 'X')
            ylabel(app.PlotY_2, 'Y')
            zlabel(app.PlotY_2, 'Z')
            app.PlotZ_2 = uiaxes(app.TranslationalTab);
            title(app.PlotZ_2, 'Title')
            xlabel(app.PlotZ_2, 'X')
            ylabel(app.PlotZ_2, 'Y')
            zlabel(app.PlotZ_2, 'Z')
            app.PlotErrorZ_2 = uiaxes(app.TranslationalTab);
            title(app.PlotErrorZ_2, 'Title')
            xlabel(app.PlotErrorZ_2, 'X')
            ylabel(app.PlotErrorZ_2, 'Y')
            zlabel(app.PlotErrorZ_2, 'Z')
            app.PlotErrorY_2 = uiaxes(app.TranslationalTab);
            title(app.PlotErrorY_2, 'Title')
            xlabel(app.PlotErrorY_2, 'X')
            ylabel(app.PlotErrorY_2, 'Y')
            zlabel(app.PlotErrorY_2, 'Z')
            app.PlotErrorX_2 = uiaxes(app.TranslationalTab);
            title(app.PlotErrorX_2, 'Title')
            xlabel(app.PlotErrorX_2, 'X')
            ylabel(app.PlotErrorX_2, 'Y')
            zlabel(app.PlotErrorX_2, 'Z')
            app.UEditField_2Label = uilabel(app.TranslationalTab);
            app.UEditField_2Label.Text = 'U = ';
            app.UEditField_2 = uieditfield(app.TranslationalTab, 'numeric');
            app.SUBMITButton_2 = uibutton(app.TranslationalTab, 'push');
            app.SUBMITButton_2.Text = 'SUBMIT';
            app.VEditField_2Label = uilabel(app.TranslationalTab);
            app.VEditField_2Label.Text = 'V = ';
            app.VEditField_2 = uieditfield(app.TranslationalTab, 'numeric');
            app.WEditField_2Label = uilabel(app.TranslationalTab);
            app.WEditField_2Label.Text = 'W = ';
            app.WEditField_2 = uieditfield(app.TranslationalTab, 'numeric');
            app.RandomButton_2 = uibutton(app.TranslationalTab, 'push');
            app.RandomButton_2.Text = 'Random';
            app.UserDefinedButton_2 = uibutton(app.TranslationalTab, 'push');
            app.UserDefinedButton_2.Text = 'User-Defined';
            app.Label_3 = uilabel(app.TranslationalTab);
            app.Label_3.Text = '0.0000';
            app.PerturbationTypeLabel_2 = uilabel(app.TranslationalTab);
            app.PerturbationTypeLabel_2.Text = 'Perturbation Type: ';
            app.Label_4 = uilabel(app.TranslationalTab);
            app.Label_4.Text = ' ';
            app.Lamp_2 = uilamp(app.TranslationalTab);
            app.STATUSLabel_2 = uilabel(app.TranslationalTab);
            app.STATUSLabel_2.Text = '[ STATUS ]';
            app.Image2 = uiimage(app.TranslationalTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Translational_GUI.png');
            app.YLabel = uilabel(app.TranslationalTab);
            app.YLabel.Text = 'Y = ';
            app.EditField_4 = uieditfield(app.TranslationalTab, 'numeric');
            app.ZLabel = uilabel(app.TranslationalTab);
            app.ZLabel.Text = 'Z = ';
            app.EditField_5 = uieditfield(app.TranslationalTab, 'numeric');
            app.XEditFieldLabel = uilabel(app.TranslationalTab);
            app.XEditFieldLabel.Text = 'X = ';
            app.XEditField = uieditfield(app.TranslationalTab, 'numeric');
            app.RandomButton_3 = uibutton(app.TranslationalTab, 'push');
            app.RandomButton_3.ButtonPushedFcn = createCallbackFcn(app, @RandomButtonPushed, true);
            app.RandomButton_3.Text = 'Random';
            app.UserDefinedButton_3 = uibutton(app.TranslationalTab, 'push');
            app.UserDefinedButton_3.ButtonPushedFcn = createCallbackFcn(app, @UserDefinedButtonPushed, true);
            app.UserDefinedButton_3.Text = 'User-Defined';
            app.Lamp_3 = uilamp(app.TranslationalTab);
            app.STATUSLabel_3 = uilabel(app.TranslationalTab);
            app.STATUSLabel_3.Text = '[ STATUS ]';
            app.SUBMITButton_3 = uibutton(app.TranslationalTab, 'push');
            app.SUBMITButton_3.ButtonPushedFcn = createCallbackFcn(app, @SUBMITButtonPushed, true);
            app.SUBMITButton_3.Text = 'SUBMIT';
            app.Label_7 = uilabel(app.TranslationalTab);
            app.Label_7.Text = '0.0000';
            app.PerturbationTypeLabel_3 = uilabel(app.TranslationalTab);
            app.PerturbationTypeLabel_3.Text = 'Perturbation Type: ';
            app.Label_8 = uilabel(app.TranslationalTab);
            app.Label_8.Text = ' ';
            app.mmLabel = uilabel(app.TranslationalTab);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.TranslationalTab);
            app.mmLabel_2.Text = 'mm';
            app.mmLabel_3 = uilabel(app.TranslationalTab);
            app.mmLabel_3.Text = 'mm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HexaTrack_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end