classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        HexapodPIGSCTab           matlab.ui.container.Tab
        StatusLabel               matlab.ui.control.Label
        Lamp_3                    matlab.ui.control.Lamp
        Lamp_2                    matlab.ui.control.Lamp
        ConnectionStatusButton    matlab.ui.control.Button
        CenterButton              matlab.ui.control.Button
        Axis1EditField_3          matlab.ui.control.NumericEditField
        Axis1EditField_2          matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        A3Label                   matlab.ui.control.Label
        A2Label                   matlab.ui.control.Label
        A1Label                   matlab.ui.control.Label
        Axis1EditField            matlab.ui.control.NumericEditField
        AbsoluteMaximumTranslationLabel  matlab.ui.control.Label
        InputAxesLabel            matlab.ui.control.Label
        SwitchStatusLabel         matlab.ui.control.Label
        Slider_MaxLabel           matlab.ui.control.Label
        Lamp                      matlab.ui.control.Lamp
        Axis3Slider               matlab.ui.control.Slider
        Axis2Slider               matlab.ui.control.Slider
        Axis1Slider               matlab.ui.control.Slider
        Switch                    matlab.ui.control.Switch
        Input_AxesLabel           matlab.ui.control.Label
        Switch_StatusLabel        matlab.ui.control.Label
        SubmitButton              matlab.ui.control.Button
        EditField                 matlab.ui.control.EditField
        InputDesiredAxesLabel     matlab.ui.control.Label
        HexapodControlInterfacev12Label  matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        DataComparisonTab         matlab.ui.container.Tab
        Lamp_5                    matlab.ui.control.Lamp
        Lamp_4                    matlab.ui.control.Lamp
        ConnectionStatusButton_2  matlab.ui.control.Button
        CenterButton_2            matlab.ui.control.Button
        Image2                    matlab.ui.control.Image
        LogTab                    matlab.ui.container.Tab
        VersionsTextArea          matlab.ui.control.TextArea
        VersionsTextAreaLabel     matlab.ui.control.Label
        ToDoTextArea              matlab.ui.control.TextArea
        ToDoTextAreaLabel         matlab.ui.control.Label
    end

    
    properties (Access = public)
        ButtonPressed = false; % Description
    end
    
    methods (Access = public)
        
         function updateData(app, slider_max)
        % Update an app property
        % app.dataValue = slider_max;
        
        % Update the label's text to display the new value
        app.Slider_MaxLabel.Text = num2str(slider_max);

         app.Axis1Slider.Limits = [-slider_max slider_max];
            app.Axis2Slider.Limits = [-slider_max slider_max];
             app.Axis3Slider.Limits = [-slider_max slider_max];

         end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % app.UIFigure.WindowState = 'maximized';
    %         screenSize = get(0, 'screensize'); % Get the screen size
    % screenWidth = screenSize(3);
    % screenHeight = screenSize(4);
    % appWidth = 1920;
    % appHeight = 1080;
    % xPos = (screenWidth - appWidth) / 2;
    % yPos = (screenHeight - appHeight) / 2;
    % 
    % % Set the app's position to 1920x1080 and center it
    % app.UIFigure.Position = [xPos, yPos, appWidth, appHeight];
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch_status = value;
            app.Switch_StatusLabel.Text = switch_status;
            assignin('base', 'switch_status', switch_status);


            if strcmp(app.Switch.Value, 'Off')
                app.Switch.FontColor = [1, 0, 0];
            elseif strcmp(app.Switch.Value, 'On')
                 app.Switch.FontColor = [0, 1, 0];

            end
       
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            input_axes = app.EditField.Value;
            app.Input_AxesLabel.Text = input_axes;
            assignin('base', 'input_axes', input_axes);
            app.Lamp.Color = [0, 0, 1];
            pause(0.25)
            app.Lamp.Color = [0, 1, 0];


            switch length(input_axes)
                case 1
                    app.A1Label.Text = input_axes(1);
                    app.A2Label.Text = ' ';
                    app.A3Label.Text = ' ';

                case 2
                    app.A1Label.Text = input_axes(1);
                    app.A2Label.Text = input_axes(2);
                    app.A3Label.Text = ' ';

                case 3
                    app.A1Label.Text = input_axes(1);
                    app.A2Label.Text = input_axes(2);
                    app.A3Label.Text = input_axes(3);

                otherwise
                    app.A1Label.Text = 'error';
                    app.A2Label.Text = 'error';
                    app.A3Label.Text = 'error';


            end
        end

        % Value changing function: Axis1Slider
        function Axis1SliderValueChanging(app, event)

    assignin('base', 'axis1_command', event.Value);
    app.Axis1EditField.Value = event.Value;

        end

        % Value changing function: Axis2Slider
        function Axis2SliderValueChanging(app, event)
           
            assignin('base', 'axis2_command', event.Value);
           app.Axis1EditField_2.Value = event.Value;
        end

        % Value changing function: Axis3Slider
        function Axis3SliderValueChanging(app, event)
            assignin('base', 'axis3_command', event.Value);
            app.Axis1EditField_3.Value = event.Value;
            
        end

        % Button pushed function: ConnectionStatusButton
        function ConnectionStatusButtonPushed(app, event)

        % Nothing 


        end

        % Button pushed function: CenterButton
        function CenterButtonPushed(app, event)
            app.ButtonPressed = true;
            assignin ('base', 'button_status',app.ButtonPressed)
            disp('Centering Hexapod.....')
            C887.FRF('X');
            pause(5)
            disp('Hexapod centered...')


        end

        % Callback function
        function STOPButtonPushed(app, event)
            close all
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HexapodPIGSCTab = uitab(app.TabGroup);
            app.HexapodPIGSCTab.Title = 'Hexapod (PI/GSC)';
            app.Image = uiimage(app.HexapodPIGSCTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'GUI.png');
            app.HexapodControlInterfacev12Label = uilabel(app.HexapodPIGSCTab);
            app.HexapodControlInterfacev12Label.Text = 'Hexapod Control Interface v1.2';
            app.InputDesiredAxesLabel = uilabel(app.HexapodPIGSCTab);
            app.InputDesiredAxesLabel.Text = {'Input'; 'Desired'; 'Axes:'};
            app.EditField = uieditfield(app.HexapodPIGSCTab, 'text');
            app.SubmitButton = uibutton(app.HexapodPIGSCTab, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.Switch_StatusLabel = uilabel(app.HexapodPIGSCTab);
            app.Switch_StatusLabel.Text = 'Switch_Status';
            app.Input_AxesLabel = uilabel(app.HexapodPIGSCTab);
            app.Input_AxesLabel.Text = 'Input_Axes';
            app.Switch = uiswitch(app.HexapodPIGSCTab, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Axis1Slider = uislider(app.HexapodPIGSCTab);
            app.Axis1Slider.Limits = [-50 50];
            app.Axis1Slider.ValueChangingFcn = createCallbackFcn(app, @Axis1SliderValueChanging, true);
            app.Axis2Slider = uislider(app.HexapodPIGSCTab);
            app.Axis2Slider.Limits = [-50 50];
            app.Axis2Slider.ValueChangingFcn = createCallbackFcn(app, @Axis2SliderValueChanging, true);
            app.Axis3Slider = uislider(app.HexapodPIGSCTab);
            app.Axis3Slider.Limits = [-50 50];
            app.Axis3Slider.ValueChangingFcn = createCallbackFcn(app, @Axis3SliderValueChanging, true);
            app.Lamp = uilamp(app.HexapodPIGSCTab);
            app.Slider_MaxLabel = uilabel(app.HexapodPIGSCTab);
            app.Slider_MaxLabel.Text = 'Slider_Max';
            app.SwitchStatusLabel = uilabel(app.HexapodPIGSCTab);
            app.SwitchStatusLabel.Text = 'Switch Status:';
            app.InputAxesLabel = uilabel(app.HexapodPIGSCTab);
            app.InputAxesLabel.Text = 'Input Axes:';
            app.AbsoluteMaximumTranslationLabel = uilabel(app.HexapodPIGSCTab);
            app.AbsoluteMaximumTranslationLabel.Text = {'Absolute '; 'Maximum'; 'Translation:'};
            app.Axis1EditField = uieditfield(app.HexapodPIGSCTab, 'numeric');
            app.Axis1EditField.ValueDisplayFormat = '%3.2g';
            app.A1Label = uilabel(app.HexapodPIGSCTab);
            app.A1Label.Text = 'A1';
            app.A2Label = uilabel(app.HexapodPIGSCTab);
            app.A2Label.Text = 'A2';
            app.A3Label = uilabel(app.HexapodPIGSCTab);
            app.A3Label.Text = 'A3';
            app.Label = uilabel(app.HexapodPIGSCTab);
            app.Label.Text = '[                  ]';
            app.Axis1EditField_2 = uieditfield(app.HexapodPIGSCTab, 'numeric');
            app.Axis1EditField_2.ValueDisplayFormat = '%3.2g';
            app.Axis1EditField_3 = uieditfield(app.HexapodPIGSCTab, 'numeric');
            app.Axis1EditField_3.ValueDisplayFormat = '%3.2g';
            app.CenterButton = uibutton(app.HexapodPIGSCTab, 'push');
            app.CenterButton.ButtonPushedFcn = createCallbackFcn(app, @CenterButtonPushed, true);
            app.CenterButton.Text = 'Center';
            app.ConnectionStatusButton = uibutton(app.HexapodPIGSCTab, 'push');
            app.ConnectionStatusButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectionStatusButtonPushed, true);
            app.ConnectionStatusButton.Text = 'Connection Status';
            app.Lamp_2 = uilamp(app.HexapodPIGSCTab);
            app.Lamp_3 = uilamp(app.HexapodPIGSCTab);
            app.StatusLabel = uilabel(app.HexapodPIGSCTab);
            app.StatusLabel.Text = 'Status:';
            app.DataComparisonTab = uitab(app.TabGroup);
            app.DataComparisonTab.Title = 'Data Comparison';
            app.Image2 = uiimage(app.DataComparisonTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'GUI2.png');
            app.CenterButton_2 = uibutton(app.DataComparisonTab, 'push');
            app.CenterButton_2.Text = 'Center';
            app.ConnectionStatusButton_2 = uibutton(app.DataComparisonTab, 'push');
            app.ConnectionStatusButton_2.Text = {'Connection'; 'Status'};
            app.Lamp_4 = uilamp(app.DataComparisonTab);
            app.Lamp_5 = uilamp(app.DataComparisonTab);
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.ToDoTextAreaLabel = uilabel(app.LogTab);
            app.ToDoTextAreaLabel.Text = 'To-Do :';
            app.ToDoTextArea = uitextarea(app.LogTab);
            app.ToDoTextArea.Value = {'- Adjust Axis1/2/3 Titles so that they reflect the desired inputs - Done 3/7/24'; '- Display input values in boxes at bottom right - done'; '- Write code to execute C887.MOV commands - done'; ''; ''; '- add 4, 5, 6 axes'; '-fix STOP block & connect block'; ''; ''; '3/7/24'};
            app.VersionsTextAreaLabel = uilabel(app.LogTab);
            app.VersionsTextAreaLabel.Text = 'Versions';
            app.VersionsTextArea = uitextarea(app.LogTab);
            app.VersionsTextArea.Value = {'1.0 - first iteration with basic dynamic capabilities'; '1.1 - added GUI cosmetic elements, dynamic display of hexapod inputs'; '1.2 - added capabilities to switch between difference input axes and update bounds dynamically without re starting the code'; ''; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end